classdef Run_Improved_V2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        SensorReading               matlab.ui.Figure
        Graph                       matlab.ui.control.UIAxes
        SamplesLabel                matlab.ui.control.Label
        Samples                     matlab.ui.control.NumericEditField
        DataAvailablilityLampLabel  matlab.ui.control.Label
        DataAvailablilityLamp       matlab.ui.control.Lamp
        PortEditFieldLabel          matlab.ui.control.Label
        PortEditField               matlab.ui.control.EditField
        IdleLabel                   matlab.ui.control.Label
        Connect                     matlab.ui.control.Button
        StartButton                 matlab.ui.control.Button
        p1EditFieldLabel            matlab.ui.control.Label
        p1EditField                 matlab.ui.control.NumericEditField
        p2EditFieldLabel            matlab.ui.control.Label
        p2EditField                 matlab.ui.control.NumericEditField
        LinearRegressionCoefffxp1xp2Label  matlab.ui.control.Label
        ActivateCheckBox            matlab.ui.control.CheckBox
    end

    properties (Access = public)
        s % Serial
        readings % Data
        fileName % Saved CSV file
        h % Animation Variable
    end

    methods (Access = private)
    
        function SetupVariables(app)
           app.fileName = sprintf('Readings %s.csv', datestr(now, 'yyyy-mm-dd-hh-MM-ss'));
            app.p1EditField.Editable = 'off';
            app.p2EditField.Editable = 'off';
            app.ActivateCheckBox.Enable = 'off';
            
            % Load warning sound
            load gong.mat;
            
            % Data variables
            samples = app.Samples.Value;
            app.readings = zeros(samples,9);
            abs_time = zeros(samples,6);
            rel_time = zeros(samples,1);
            temperature = zeros(samples,1);
            cal_temperature = zeros(samples,1);
            
            % Plotting variables
            app.h = animatedline(app.Graph);
            app.h.Marker = 'o';
            app.h.Color = 'blue';
            clearpoints(app.h);
            drawnow
        end
        
    end


    methods (Access = private)

        % Button pushed function: Connect
        function ConnectButtonPushed(app, event)
            delete(instrfind);
            cla(app.Graph);
            % Serial comm
            try
                app.s = serial(app.PortEditField.Value); % need to determine which port is being connected
                set(app.s,'timeout',1);
                fopen(app.s);
                app.IdleLabel.Text = 'Connected!';
            catch
                app.IdleLabel.Text = 'Error: cannot find the port';
                fprintf('Error: cannot find the port.\n');
                return;
            end
        end

        % Close request function: SensorReading
        function SensorReadingCloseRequest(app, event)
            res = questdlg('Are you sure you want to quit?','Terminating','Yes','No','No');
            switch res
                case 'Yes'
                    try
                        % Export to file
                        csvwrite(app.fileName,app.readings)
                        fprintf('Data Automatically Saved in "%s"! \n',app.fileName);
                    catch
                        fprintf('Error: nothing is saved.\n');
                    end
                    delete(app);
                    delete(instrfind);
                case 'No'
                    return;
                case ''
                    return;
            end
        end

        % Button pushed function: StartButton
        function StartButtonPushed(app, event)
            SetupVariables(app);
            flushinput(app.s);
            % Actual program starts here
            for i = 1:samples
                app.IdleLabel.Text = 'Reading...';
                
                % Get data and rel_time
                if i == 1
                    tic;
                end
                try
                    [data,count,msg] = fgetl(app.s);
                    msg = '';
                catch
                    app.IdleLabel.Text = 'Error: connect a port first';
                    fprintf('Error: connect a port first.\n');
                    app.p1EditField.Editable = 'on';
                    app.p2EditField.Editable = 'on';
                    app.ActivateCheckBox.Enable = 'on';
                    return;
                end
                abs_time(i,:) = clock;
                rel_time(i) = toc;
                
                if isempty(data)
                    temperature(i) = nan;
                    cal_temperature(i) = nan;
                    app.DataAvailablilityLamp.Color = 'red';
                    app.IdleLabel.Text = 'No input data';
                    fprintf('Error: No input data at Sample# %d at %.4f.\n', i,rel_time(i));
                else
                    % Convert BIN to Celsius
                    str_temp = data;
                    str_temp(192) = '1';
                    try
                        str_trun =  char(bin2dec(reshape(str_temp(169:192),8,[]).').');
                        str_flip = fliplr(str_trun);
                        temperature(i) = hex2dec(str_flip)/10;
                        if (app.ActivateCheckBox.Value) % apply calibration coefficients if checked
                            cal_temperature(i) = app.p2EditField.Value + temperature(i)*app.p1EditField.Value;
                        else
                            cal_temperature(i) = nan;
                        end
                        app.DataAvailablilityLamp.Color = 'green';
                    catch
                        app.IdleLabel.Text = 'Bad input';
                        fprintf('Error: Bad input at Sample# %d at %.4f.\n', i,rel_time(i));
                        temperature(i) = nan;
                        soundsc(y); % play warning sound
                        app.DataAvailablilityLamp.Color = 'yellow';
                    end
                end
                
                % Animate graph
                addpoints(app.h,rel_time(i),temperature(i));
                drawnow
                try
                app.readings(i,:) = [abs_time(i,:) rel_time(i) temperature(i) cal_temperature(i)];
                catch
                   return;
                   fprintf("Program Terminated!\n");
                end
            end
            % Export to file
            csvwrite(app.fileName,app.readings)
            fprintf('Data Automatically Saved in "%s"! \n',app.fileName);
            
            app.IdleLabel.Text = 'Done';
            app.p1EditField.Editable = 'on';
            app.p2EditField.Editable = 'on';
            app.ActivateCheckBox.Enable = 'on';
            app.DataAvailablilityLamp.Color = 'black';
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.SensorReading = uifigure;
            app.SensorReading.Name = 'UI Figure';
            app.SensorReading.CloseRequestFcn = createCallbackFcn(app, @SensorReadingCloseRequest, true);
            app.Graph = uiaxes(app.SensorReading);
            title(app.Graph, 'Temperature vs Time')
            xlabel(app.Graph, 'Time (Second)')
            ylabel(app.Graph, 'Temperature (C)')
            app.SamplesLabel = uilabel(app.SensorReading);
            app.SamplesLabel.Text = 'Samples ';
            app.Samples = uieditfield(app.SensorReading, 'numeric');
            app.Samples.Limits = [1 Inf];
            app.Samples.Value = 10;
            app.DataAvailablilityLampLabel = uilabel(app.SensorReading);
            app.DataAvailablilityLampLabel.Text = 'Data Availablility';
            app.DataAvailablilityLamp = uilamp(app.SensorReading);
            app.PortEditFieldLabel = uilabel(app.SensorReading);
            app.PortEditFieldLabel.Text = 'Port';
            app.PortEditField = uieditfield(app.SensorReading, 'text');
            app.PortEditField.Value = 'COM3';
            app.IdleLabel = uilabel(app.SensorReading);
            app.IdleLabel.Text = 'Idle';
            app.Connect = uibutton(app.SensorReading, 'push');
            app.Connect.ButtonPushedFcn = createCallbackFcn(app, @ConnectButtonPushed, true);
            app.Connect.Text = 'Connect/Reset';
            app.StartButton = uibutton(app.SensorReading, 'push');
            app.StartButton.ButtonPushedFcn = createCallbackFcn(app, @StartButtonPushed, true);
            app.StartButton.Text = 'Start';
            app.p1EditFieldLabel = uilabel(app.SensorReading);
            app.p1EditFieldLabel.Text = 'p1';
            app.p1EditField = uieditfield(app.SensorReading, 'numeric');
            app.p1EditField.ValueDisplayFormat = '%11.6g';
            app.p1EditField.Value = 1.10057;
            app.p2EditFieldLabel = uilabel(app.SensorReading);
            app.p2EditFieldLabel.Text = 'p2';
            app.p2EditField = uieditfield(app.SensorReading, 'numeric');
            app.p2EditField.ValueDisplayFormat = '%11.6g';
            app.p2EditField.Value = -4.7069;
            app.LinearRegressionCoefffxp1xp2Label = uilabel(app.SensorReading);
            app.LinearRegressionCoefffxp1xp2Label.Text = 'Linear Regression Coeff. f(x) = p1*x+p2';
            app.ActivateCheckBox = uicheckbox(app.SensorReading);
            app.ActivateCheckBox.Text = 'Activate';
            app.ActivateCheckBox.Value = true;
        end
    end

    methods (Access = public)

        % Construct app
        function app = Run_Improved_V2

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.SensorReading)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.SensorReading)
        end
    end
end